classdef HSI_CS_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        compressTab          matlab.ui.container.Tab
        SelectFilesButton    matlab.ui.control.Button
        FilesListBoxLabel    matlab.ui.control.Label
        FilesListBox         matlab.ui.control.ListBox
        RatioSliderLabel     matlab.ui.control.Label
        RatioSlider          matlab.ui.control.Slider
        TimesSliderLabel     matlab.ui.control.Label
        TimesSlider          matlab.ui.control.Slider
        RunButton            matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        decompressTab        matlab.ui.container.Tab
        FilesListBox_2Label  matlab.ui.control.Label
        FilesListBox_2       matlab.ui.control.ListBox
        RunButton_2          matlab.ui.control.Button
        SelectFilesButton_2  matlab.ui.control.Button
        ListBox_2            matlab.ui.control.ListBox
        helpTab              matlab.ui.container.Tab
        TextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        files % Description
        paths
        ratio = 0.2;
        times = 20;
        message;
        binpath;
    end
    
    methods (Access = private)
        
        function compress(app, filename, times)
            exec = ['"', app.binpath, 'opj_compress.exe"'];
            param1 = [' -i ', '"', filename, '.tif"'];
            param2 = [' -o ', '"', filename, '.j2k"'];
            param3 = [' -r ', num2str(times)];
            cmd = [exec, param1, param2, param3];
            [~, ~] = system(cmd);
        end
        
        function decompress(app, filename)
            exec = ['"', app.binpath, 'opj_decompress.exe"'];
            param1 = [' -i ', '"', filename, '.j2k"'];
            param2 = [' -o ', '"', filename, '.tif"'];
            cmd = [exec, param1, param2];
            [~, ~] = system(cmd);
            delete([filename, '.j2k']);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        toolbox = matlab.apputil.getInstalledAppInfo;
            for i = 1:length(toolbox)
                if strcmp(toolbox(i).name, 'HSI_CS_Toolbox')
                    addpath(genpath(toolbox(i).location));
                end
            end
            app.binpath = getbinpath;
            app.binpath = app.binpath(1:end-10);
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            app.SelectFilesButton.Enable = 0;
            [app.files, app.paths] = uigetfile('*', 'MultiSelect', 'on');
            app.SelectFilesButton.Enable = 1;
            figure(app.UIFigure);
            if ~iscell(app.files)
                if app.files ~= 0
                    app.files = {app.files};
                    app.FilesListBox.Items = app.files;
                    app.RunButton.Enable = 1;
                end
            else
                app.FilesListBox.Items = app.files;
                app.RunButton.Enable = 1;
            end
        end

        % Value changed function: RatioSlider
        function RatioSliderValueChanged(app, event)
            value = app.RatioSlider.Value;
            app.ratio = value;
        end

        % Value changed function: TimesSlider
        function TimesSliderValueChanged(app, event)
            value = app.TimesSlider.Value;
            app.times = round(value);
        end

        % Button pushed function: SelectFilesButton_2
        function SelectFilesButton_2Pushed(app, event)
            app.SelectFilesButton_2.Enable = 0;
            [app.files, app.paths] = uigetfile('*.tar', 'MultiSelect', 'on');
            app.SelectFilesButton_2.Enable = 1;
            figure(app.UIFigure);
            if ~iscell(app.files)
                if app.files ~= 0
                    app.files = {app.files};
                    app.FilesListBox_2.Items = app.files;
                    app.RunButton_2.Enable = 1;
                end
            else
                app.FilesListBox_2.Items = app.files;
                app.RunButton_2.Enable = 1;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 0;
            app.SelectFilesButton.Enable = 0;
            app.RunButton_2.Enable = 0;
            app.SelectFilesButton_2.Enable = 0;
            app.RatioSlider.Enable = 0;
            app.TimesSlider.Enable = 0;
            
            app.message = {};
            filenum = length(app.files);
            for i = 1:filenum
                process = [num2str(i), '/', num2str(filenum), ' '];
                [~, name, ext] = fileparts(char(app.files(i)));
                
                app.message = cat(1, app.message, [process, 'Reading ', name, '.']);
                app.ListBox.Items = app.message;
                scroll(app.ListBox, 'bottom');
                drawnow;
                
                foldname = [app.paths, 'compressed', filesep, name];
                if exist(foldname, "dir")
                    rmdir(foldname, "s");
                    mkdir(foldname);
                else
                    mkdir(foldname);
                end

                filename = [app.paths, name, ext];
                [X, param] = HSIReader(filename);
                
                [m, n, d] = size(X);
                X = reshape(X, m*n, d);
                Xm = mean(X, 2);
                X = X - Xm;
                Xm = reshape(Xm, m, n);
            
                k = ceil(d * app.ratio);
                scaler = zeros([k+1, 2]);
                scaler(end, 1) = max(Xm(:));
                scaler(end, 2) = min(Xm(:));
            
                imwrite(uint16(mat2gray(Xm).*65535), [foldname, filesep, '0.tif']);
                compress(app, [foldname, filesep, '0'], app.times);
                delete([foldname, filesep, '0.tif']);
                
                app.message = cat(1, app.message, [process, 'Analysing the principle components.']);
                app.ListBox.Items = app.message;
                scroll(app.ListBox, 'bottom');
                drawnow;
                
                R = X' * X;
                [U, ~, ~] = svds(R, k);
                
                Xk = X * U;
                scaler(1:end-1, 1) = max(Xk, [], 1);
                scaler(1:end-1, 2) = min(Xk, [], 1);
                
                app.message = cat(1, app.message, [process, 'Compressing ', '0%']);
                for j = 1:k
                    app.message(end) = {[process, 'Compressing ', num2str(100*j/k, '%.1f'), '%.']};
                    app.ListBox.Items = app.message;
                    scroll(app.ListBox, 'bottom');
                    drawnow;
                
                    im = reshape(Xk(:, j), m, n);
                    imwrite(uint16(mat2gray(im).*65535), [foldname, filesep, num2str(j), '.tif']);
                    compress(app, [foldname, filesep, num2str(j)], app.times);
                    delete([foldname, filesep, num2str(j), '.tif']);
                end
                
                app.message = cat(1, app.message, [process, 'Packaging all files.']);
                app.message = cat(1, app.message, newline);
                app.ListBox.Items = app.message;
                scroll(app.ListBox, 'bottom');
                drawnow;
                
                param.U = U;
                param.scaler = scaler;
                save([foldname, filesep, 'param.mat'], 'param');
                copyfile([app.paths, name, '.hdr'], foldname);
                tar([foldname, '.tar'], foldname);
                rmdir(foldname, "s");
            end
            app.message = cat(1, app.message, 'All done!!!');
            app.ListBox.Items = app.message;
            scroll(app.ListBox, 'bottom');
            drawnow;
            
            app.RunButton.Enable = 1;
            app.SelectFilesButton.Enable = 1;
            app.RunButton_2.Enable = 1;
            app.SelectFilesButton_2.Enable = 1;
            app.RatioSlider.Enable = 1;
            app.TimesSlider.Enable = 1;
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            app.RunButton.Enable = 0;
            app.SelectFilesButton.Enable = 0;
            app.RunButton_2.Enable = 0;
            app.SelectFilesButton_2.Enable = 0;
            app.RatioSlider.Enable = 0;
            app.TimesSlider.Enable = 0;
                        
            app.message = {};
            filenum = length(app.files);
            for i = 1:filenum
                process = [num2str(i), '/', num2str(filenum), ' '];
                name = char(app.files(i));
                name = name(1:end-4);
                
                app.message = cat(1, app.message, [process, 'Unpacking ', name, '.']);
                app.ListBox_2.Items = app.message;
                scroll(app.ListBox_2, 'bottom');
                drawnow;
                
                filename = [app.paths, name, '.tar'];
                foldname = [app.paths, 'decompressed', filesep];
                untar(filename, foldname);
                foldname = [app.paths, 'decompressed', filesep, name];
                
                param = importdata([foldname, filesep, 'param.mat']);
                delete([foldname, filesep, 'param.mat']);
                U = param.U;
                scaler = param.scaler;
                k = length(dir([foldname, filesep, '*.j2k'])) - 1;
                
                app.message = cat(1, app.message, [process, 'Decompressing ', '0%']);
                app.ListBox_2.Items = app.message;
                scroll(app.ListBox_2, 'bottom');
                drawnow;
                
                decompress(app, [foldname, filesep, '0']);
                Xm = imread([foldname, filesep, '0.tif']);
                delete([foldname, filesep, '0.tif']);
                [m, n] = size(Xm);
                Xm = double(reshape(Xm, m*n, 1)) / 65535;
                Xm = Xm * (scaler(end, 1) - scaler(end, 2)) + scaler(end, 2);
            
                Xk = zeros([m, n, k]);
                for j = 1:k
                    app.message(end) = {[process, 'Decompressing ', num2str(100*j/k, '%.1f'), '%.']};
                    app.ListBox_2.Items = app.message;
                    scroll(app.ListBox_2, 'bottom');
                    drawnow;
                    
                    decompress(app, [foldname, filesep, num2str(j)]);
                    Xk(:, :, j) = imread([foldname, filesep, num2str(j), '.tif']);
                    delete([foldname, filesep, num2str(j), '.tif']);
                end
                
                app.message = cat(1, app.message, [process, 'Reconstructing the data.']);
                app.ListBox_2.Items = app.message;
                scroll(app.ListBox_2, 'bottom');
                drawnow;
                
                Xk = reshape(Xk, m*n, []) / 65535;
                Xk = Xk .* (scaler(1:end-1, 1)' - scaler(1:end-1, 2)') + scaler(1:end-1, 2)';
            
                X = Xk * U' + Xm;
                X = reshape(X, m, n, []);
                
                app.message = cat(1, app.message, [process, 'Writing ', name, '.']);
                app.message = cat(1, app.message, newline);
                app.ListBox_2.Items = app.message;
                scroll(app.ListBox_2, 'bottom');
                drawnow;
                
                multibandwrite(X, [foldname, filesep, name, '.img'], ...
                    param.interleave, 'offset', param.offset, ...
                    'precision', param.precision, 'machfmt', param.byteorder);
            end
            
            app.message = cat(1, app.message, 'All done!!!');
            app.ListBox_2.Items = app.message;
            scroll(app.ListBox_2, 'bottom');
            drawnow;
            
            app.RunButton.Enable = 1;
            app.SelectFilesButton.Enable = 1;
            app.RunButton_2.Enable = 1;
            app.SelectFilesButton_2.Enable = 1;
            app.RatioSlider.Enable = 1;
            app.TimesSlider.Enable = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'HSI_CS_Toolbox';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.compressTab = uitab(app.TabGroup);
            app.compressTab.Title = 'compress';
            app.SelectFilesButton = uibutton(app.compressTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesListBoxLabel = uilabel(app.compressTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.compressTab);
            app.FilesListBox.Items = {};
            app.FilesListBox.Value = {};
            app.RatioSliderLabel = uilabel(app.compressTab);
            app.RatioSliderLabel.Text = 'Ratio';
            app.RatioSlider = uislider(app.compressTab);
            app.RatioSlider.Limits = [0 1];
            app.RatioSlider.ValueChangedFcn = createCallbackFcn(app, @RatioSliderValueChanged, true);
            app.RatioSlider.Value = 0.2;
            app.TimesSliderLabel = uilabel(app.compressTab);
            app.TimesSliderLabel.Text = 'Times';
            app.TimesSlider = uislider(app.compressTab);
            app.TimesSlider.Limits = [1 50];
            app.TimesSlider.ValueChangedFcn = createCallbackFcn(app, @TimesSliderValueChanged, true);
            app.TimesSlider.Value = 20;
            app.RunButton = uibutton(app.compressTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ListBox = uilistbox(app.compressTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.decompressTab = uitab(app.TabGroup);
            app.decompressTab.Title = 'decompress';
            app.FilesListBox_2Label = uilabel(app.decompressTab);
            app.FilesListBox_2Label.Text = 'Files';
            app.FilesListBox_2 = uilistbox(app.decompressTab);
            app.FilesListBox_2.Items = {};
            app.FilesListBox_2.Value = {};
            app.RunButton_2 = uibutton(app.decompressTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Enable = 'off';
            app.RunButton_2.Text = 'Run';
            app.SelectFilesButton_2 = uibutton(app.decompressTab, 'push');
            app.SelectFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButton_2Pushed, true);
            app.SelectFilesButton_2.Text = 'Select Files';
            app.ListBox_2 = uilistbox(app.decompressTab);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.helpTab = uitab(app.TabGroup);
            app.helpTab.Title = 'help';
            app.TextArea = uitextarea(app.helpTab);
            app.TextArea.Value = {'Parameters:'; ''; 'Ratio -- This is the percentage of the principle components. If you want to have the size of the output files to be small, this parameter so be small. '; ''; 'Times -- This is the compressing times of the jpeg2000 algrithm. If you want to have the size of the output files to be small, this parameter so be large. '; ''; 'It should be noted that small size of the output file means the reconstrution result would be bad.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSI_CS_Toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end